classdef Project_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EnergyEditField             matlab.ui.control.NumericEditField
        EnergyEditFieldLabel        matlab.ui.control.Label
        MedianEditField             matlab.ui.control.NumericEditField
        MedianEditFieldLabel        matlab.ui.control.Label
        ModeEditField               matlab.ui.control.NumericEditField
        ModeEditFieldLabel          matlab.ui.control.Label
        VarianceEditField           matlab.ui.control.NumericEditField
        VarianceEditFieldLabel      matlab.ui.control.Label
        StandardDeviationEditField  matlab.ui.control.NumericEditField
        StandardDeviationEditFieldLabel  matlab.ui.control.Label
        AverageEditField            matlab.ui.control.NumericEditField
        AverageEditFieldLabel       matlab.ui.control.Label
        MaximumFrequencyComponentEditField  matlab.ui.control.NumericEditField
        MaximumFrequencyComponentEditFieldLabel  matlab.ui.control.Label
        ControlPanel                matlab.ui.container.Panel
        EditField                   matlab.ui.control.EditField
        StartRecordingButton        matlab.ui.control.Button
        DurationEditField           matlab.ui.control.NumericEditField
        DurationEditFieldLabel      matlab.ui.control.Label
        UIAxes3_4                   matlab.ui.control.UIAxes
        UIAxes3_2                   matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            duration = app.DurationEditField.Value
            f = 4000;
            r = audiorecorder(f,16,1);
            app.EditField.Value = 'Start Speaking'
            recordblocking(r, duration);
            app.EditField.Value = 'End of Recording'
            signalt = getaudiodata(r);
            t = 0:1/f:duration-1/f;
            n = length(t);
            signalf = fft(signalt);
            f_d = -n/2:(n/2)-1;
            power = (abs(signalf).^2)/n;
            stem(app.UIAxes3,t, signalt, "MarkerSize",0.1,"LineWidth",0.1)
            stem(app.UIAxes3_2,f_d(n/2+1:n), abs(signalf(n/2+1:n)), "MarkerSize",0.1,"LineWidth",0.1)
            stem(app.UIAxes3_4,f_d(n/2+1:n), power(n/2+1:n), "MarkerSize",0.1,"LineWidth",0.1)
            energy = sum(abs(signalt).^2);
            max_freq_comp_index = find(abs(signalf) == max(abs(signalf)));
            max_freq_comp = f_d(max_freq_comp_index);
            max_freq_comp_1 = max_freq_comp(2)
            Average = mean(signalt);
            Standard_Deviation = std(signalt);
            Variance = Standard_Deviation^2;
            Mode = mode(signalt);
            Median = median(signalt);
            app.MaximumFrequencyComponentEditField.Value = max_freq_comp_1
            app.EnergyEditField.Value = energy
            app.AverageEditField.Value = Average
            app.StandardDeviationEditField.Value = Standard_Deviation
            app.VarianceEditField.Value = Variance
            app.ModeEditField.Value = Mode
            app.MedianEditField.Value = Median
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Signal in The Time Domain')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Amplitude')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Signal in The Frequency Domain')
            xlabel(app.UIAxes3_2, 'Frequency (Hz)')
            ylabel(app.UIAxes3_2, 'Amplitude')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_4 = uiaxes(app.UIFigure);
            title(app.UIAxes3_4, 'Power of The Signal')
            xlabel(app.UIAxes3_4, 'Frequency (Hz)')
            ylabel(app.UIAxes3_4, 'Power')
            zlabel(app.UIAxes3_4, 'Z')
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.DurationEditFieldLabel = uilabel(app.ControlPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.ControlPanel, 'numeric');
            app.DurationEditField.Value = 1;
            app.StartRecordingButton = uibutton(app.ControlPanel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.EditField = uieditfield(app.ControlPanel, 'text');
            app.MaximumFrequencyComponentEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumFrequencyComponentEditFieldLabel.Text = 'Maximum Frequency Component';
            app.MaximumFrequencyComponentEditField = uieditfield(app.UIFigure, 'numeric');
            app.AverageEditFieldLabel = uilabel(app.UIFigure);
            app.AverageEditFieldLabel.Text = 'Average';
            app.AverageEditField = uieditfield(app.UIFigure, 'numeric');
            app.StandardDeviationEditFieldLabel = uilabel(app.UIFigure);
            app.StandardDeviationEditFieldLabel.Text = 'Standard Deviation';
            app.StandardDeviationEditField = uieditfield(app.UIFigure, 'numeric');
            app.VarianceEditFieldLabel = uilabel(app.UIFigure);
            app.VarianceEditFieldLabel.Text = 'Variance ';
            app.VarianceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModeEditFieldLabel = uilabel(app.UIFigure);
            app.ModeEditFieldLabel.Text = 'Mode';
            app.ModeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MedianEditFieldLabel = uilabel(app.UIFigure);
            app.MedianEditFieldLabel.Text = 'Median';
            app.MedianEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnergyEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyEditFieldLabel.Text = 'Energy';
            app.EnergyEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end